classdef GUI_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        generalGrid                   matlab.ui.container.GridLayout
        ResultsvisualizationButton    matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        ParametersettingsPanel        matlab.ui.container.Panel
        IdentificationsettingsButton  matlab.ui.control.Button
        mzROIextractionButton         matlab.ui.control.Button
        ExperimenttypePanel           matlab.ui.container.Panel
        SignalacquisitionListBox      matlab.ui.control.ListBox
        SignalacquisitionLabel        matlab.ui.control.Label
        ROIMCRresultsPanel            matlab.ui.container.Panel
        DropDown_mzROI                matlab.ui.control.DropDown
        DropDown_ST                   matlab.ui.control.DropDown
        mzROIarrayLabel               matlab.ui.control.Label
        MatrixSTLabel                 matlab.ui.control.Label
        title                         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','ROIMCR_identification.matrix.st=[];');  % by default the selected option
            evalin('base','ROIMCR_identification.matrix.mzROI=[];');
            evalin('base','ROIMCR_identification.experiment=1;');
            evalin('base','ROIMCR_identification.settings_ROIMCRresults=[];');
            evalin('base','ROIMCR_identification.ROIMCR_signals=[];')
            evalin('base','ROIMCR_identification.settings_identification=[];');
        end

        % Drop down opening function: DropDown_ST
        function DropDownOpening(app, event)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                cell_array{i} = vars(i).name;
            end
            
            %Delete StartUp variable
            cell_array = erase(cell_array,'ROIMCR_identification');
            emptyCells = cellfun(@isempty, cell_array);
            nonEmptyCells = ~emptyCells;
            cell_array = cell_array(nonEmptyCells);

            app.DropDown_ST.Items = cell_array;
        end

        % Drop down opening function: DropDown_mzROI
        function DropDown_mzROIOpening(app, event)
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                cell_array{i} = vars(i).name;
            end
            
            %Delete StartUp variable
            cell_array = erase(cell_array,'ROIMCR_identification');
            emptyCells = cellfun(@isempty, cell_array);
            nonEmptyCells = ~emptyCells;
            cell_array = cell_array(nonEmptyCells);

            app.DropDown_mzROI.Items = cell_array;
            
        end

        % Value changed function: DropDown_ST
        function DropDown_STValueChanged(app, event)
            value_st = app.DropDown_ST.Value;
            value_st = evalin('base', value_st);

            assignin('base','value_st',value_st);
            evalin('base','ROIMCR_identification.matrix.st=value_st;'); 
            evalin('base','clear value_st');
        end

        % Value changed function: DropDown_mzROI
        function DropDown_mzROIValueChanged(app, event)
            value_mzROI = app.DropDown_mzROI.Value;
            value_mzROI = evalin('base', value_mzROI);

            assignin('base','value_mzROI',value_mzROI);
            evalin('base','ROIMCR_identification.matrix.mzROI=value_mzROI;');
            evalin('base','clear value_mzROI');
        end

        % Value changed function: SignalacquisitionListBox
        function SignalacquisitionListBoxValueChanged(app, event)
            value_experiment = app.SignalacquisitionListBox.Value;
            if strcmp(value_experiment,'ms1') %Poner de default
                experiment = 1;
            elseif strcmp(value_experiment,'ms1 + ms2')
                experiment = 2;
            elseif strcmp(value_experiment,'ms1_p + ms2_p + ms1_n + ms2_n')
                experiment = 3;
            end
            
            assignin('base','experiment',experiment);
            evalin('base','ROIMCR_identification.experiment=experiment;');
            evalin('base','clear experiment');
        end

        % Button pushed function: mzROIextractionButton
        function mzROIextractionButtonPushed(app, event)
            experiment=evalin('base','ROIMCR_identification.experiment');

            if experiment == 1
                mzROI_extraction_1
            elseif experiment == 2
                mzROI_extraction_2
            elseif experiment == 3
                mzROI_extraction_3
            end
        end

        % Button pushed function: IdentificationsettingsButton
        function IdentificationsettingsButtonPushed(app, event)
            experiment=evalin('base','ROIMCR_identification.experiment');

            if experiment == 1
                identification_1
            elseif experiment == 2
                identification_2
            elseif experiment == 3
                identification_3
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.generalGrid = uigridlayout(app.UIFigure);
            app.title = uilabel(app.generalGrid);
            app.title.Text = 'Compound identification';
            app.ROIMCRresultsPanel = uipanel(app.generalGrid);
            app.ROIMCRresultsPanel.Title = 'ROIMCR results';
            app.MatrixSTLabel = uilabel(app.ROIMCRresultsPanel);
            app.MatrixSTLabel.Text = 'Matrix ST';
            app.mzROIarrayLabel = uilabel(app.ROIMCRresultsPanel);
            app.mzROIarrayLabel.Text = 'mzROI array';
            app.DropDown_ST = uidropdown(app.ROIMCRresultsPanel);
            app.DropDown_ST.Items = {' '};
            app.DropDown_ST.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown_ST.ValueChangedFcn = createCallbackFcn(app, @DropDown_STValueChanged, true);
            app.DropDown_ST.Value = ' ';
            app.DropDown_mzROI = uidropdown(app.ROIMCRresultsPanel);
            app.DropDown_mzROI.Items = {' '};
            app.DropDown_mzROI.DropDownOpeningFcn = createCallbackFcn(app, @DropDown_mzROIOpening, true);
            app.DropDown_mzROI.ValueChangedFcn = createCallbackFcn(app, @DropDown_mzROIValueChanged, true);
            app.DropDown_mzROI.Value = ' ';
            app.ExperimenttypePanel = uipanel(app.generalGrid);
            app.ExperimenttypePanel.Title = 'Experiment type';
            app.SignalacquisitionLabel = uilabel(app.ExperimenttypePanel);
            app.SignalacquisitionLabel.Text = {'Signal '; 'acquisition'};
            app.SignalacquisitionListBox = uilistbox(app.ExperimenttypePanel);
            app.SignalacquisitionListBox.Items = {'ms1', 'ms1 + ms2', 'ms1_p + ms2_p + ms1_n + ms2_n'};
            app.SignalacquisitionListBox.ValueChangedFcn = createCallbackFcn(app, @SignalacquisitionListBoxValueChanged, true);
            app.SignalacquisitionListBox.Value = 'ms1';
            app.ParametersettingsPanel = uipanel(app.generalGrid);
            app.ParametersettingsPanel.Title = 'Parameter settings';
            app.mzROIextractionButton = uibutton(app.ParametersettingsPanel, 'push');
            app.mzROIextractionButton.ButtonPushedFcn = createCallbackFcn(app, @mzROIextractionButtonPushed, true);
            app.mzROIextractionButton.Text = {'mzROI '; 'extraction '};
            app.IdentificationsettingsButton = uibutton(app.ParametersettingsPanel, 'push');
            app.IdentificationsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @IdentificationsettingsButtonPushed, true);
            app.IdentificationsettingsButton.Text = {'Identification'; 'settings'};
            app.RunButton = uibutton(app.generalGrid, 'push');
            app.RunButton.Text = 'Run';
            app.ExitButton = uibutton(app.generalGrid, 'push');
            app.ExitButton.Text = 'Exit';
            app.ResultsvisualizationButton = uibutton(app.generalGrid, 'push');
            app.ResultsvisualizationButton.Text = {'Results'; 'visualization'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end