classdef MSident < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSidentUIFigure  matlab.ui.Figure
        Image            matlab.ui.control.Image
        MSidentLabel     matlab.ui.control.Label
        ExitButton       matlab.ui.control.Button
        StartButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','ROIMCR_identification.dd_var=[];');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            display('#################')
            display('#    LOADING    #')
            display('#################')
            pause(0.5);
            
            vars = evalin('base', 'whos');
            cell_array = cell(size(vars));
            for i=1:length(vars)
                cell_array{i} = vars(i).name;
            end
            
            % Delete StartUp variable
            cell_array = erase(cell_array,'ROIMCR_identification');
            emptyCells = cellfun(@isempty, cell_array);
            nonEmptyCells = ~emptyCells;
            cell_array = cell_array(nonEmptyCells);
            cell_array_f{1} = [];
            for i =1:size(cell_array,1)
                cell_array_f{i+1,1} = cell_array{i};
            end

            assignin('base','cell_array_f',cell_array_f);
            evalin('base','ROIMCR_identification.dd_var=cell_array_f;');
            evalin('base','clear cell_array_f');

            GUI_identification;
            close(app.MSidentUIFigure);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.MSidentUIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MSidentUIFigure = uifigure('Visible', 'off');
            app.MSidentUIFigure.Name = 'MSident';
            app.StartButton = uibutton(app.MSidentUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExitButton = uibutton(app.MSidentUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.MSidentLabel = uilabel(app.MSidentUIFigure);
            app.MSidentLabel.Text = 'MSident';
            app.Image = uiimage(app.MSidentUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Figura.PNG');
            % Show the figure after all components are created
            app.MSidentUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MSident

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSidentUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSidentUIFigure)
        end
    end
end