classdef identification_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Identificationsettingsms1UIFigure  matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        IonizactionofthesearchDropDownLabel  matlab.ui.control.Label
        IonizactionofthesearchDropDown  matlab.ui.control.DropDown
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Precursorthreshold01Label       matlab.ui.control.Label
        mzerrorppmLabel                 matlab.ui.control.Label
        DatabaseDropDown                matlab.ui.control.DropDown
        DatabaseDropDownLabel           matlab.ui.control.Label
        OKButton                        matlab.ui.control.Button
    end

    
    properties (Access = public)
        selectedVal_i1 = {};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value_db = evalin('base','ROIMCR_identification.settings_identification.databasename;');
            if isempty(value_db) 
               app.DatabaseDropDown.Value = 'Select database';
            else
                app.DatabaseDropDown.Items = {value_db};
                app.DatabaseDropDown.Value = value_db;
            end

            value_i = evalin('base','ROIMCR_identification.settings_identification.ionization;');
            if value_i == 1
                value = 'positive';
            elseif value_i == 2
                value = 'negative';
            end

            app.IonizactionofthesearchDropDown.Value = {value};

            app.EditField.Value = evalin('base','ROIMCR_identification.settings_identification.error;');
            app.EditField_2.Value = evalin('base','ROIMCR_identification.settings_identification.precursor;');
        end

        % Drop down opening function: DatabaseDropDown
        function DatabaseDropDownOpening(app, event)
            vars = evalin('base', 'whos');
            vars = vars(strcmp({vars.class}, 'struct'),:);
            cell_array = cell(size(vars));
            cell_array{1} = 'Select database';
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            
            %Delete StartUp variable
            cell_array = erase(cell_array,'ROIMCR_identification');
            emptyCells = cellfun(@isempty, cell_array);
            nonEmptyCells = ~emptyCells;
            cell_array = cell_array(nonEmptyCells);

            app.DatabaseDropDown.Items = cell_array;
        end

        % Value changed function: DatabaseDropDown
        function DatabaseDropDownValueChanged(app, event)
            database = app.DatabaseDropDown.Value;
            database_name = app.DatabaseDropDown.Value;

            %store the database and its name 
            assignin('base','database_name',database_name)
            evalin('base','ROIMCR_identification.settings_identification.databasename=database_name;') 
            evalin('base','clear database_name');
            
            database = evalin('base', database);

            assignin('base','database_selected',database)
            evalin('base','ROIMCR_identification.settings_identification.database=database_selected;') 
            evalin('base','clear database_selected'); 
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            error = app.EditField.Value;
            
            assignin('base','error',error);
            evalin('base','ROIMCR_identification.settings_identification.error=error;'); 
            evalin('base','clear error');
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            precursor = app.EditField_2.Value;
            
            assignin('base','precursor',precursor);
            evalin('base','ROIMCR_identification.settings_identification.precursor=precursor;'); 
            evalin('base','clear precursor'); 
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Figurename = app.Identificationsettingsms1UIFigure ;


            datb = evalin('base','ROIMCR_identification.settings_identification.databasename;');
            ioni = evalin('base','ROIMCR_identification.settings_identification.ionization;');
            if ioni == 1
                ioni = 'positive';
            elseif ioni == 2
                ioni = 'negative';
            end
            err = evalin('base','ROIMCR_identification.settings_identification.error;');
            p_ion = evalin('base','ROIMCR_identification.settings_identification.precursor;');

            selectedVal_i1{1} = datb;
            selectedVal_i1{2} = ioni;
            selectedVal_i1{3} = num2str(err);
            selectedVal_i1{4} = num2str(p_ion);
            setappdata(0, 'selectedVal_i1', selectedVal_i1);

            close(Figurename)
        end

        % Value changed function: IonizactionofthesearchDropDown
        function IonizactionofthesearchDropDownValueChanged(app, event)
            ionization = app.IonizactionofthesearchDropDown.Value;
            
            if strcmp(ionization,'positive')
                ionization = 1;
            elseif strcmp(ionization,'negative')
                ionization = 2;
            end

            assignin('base','ionization',ionization);
            evalin('base','ROIMCR_identification.settings_identification.ionization=ionization;'); 
            evalin('base','clear ionization');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Identificationsettingsms1UIFigure = uifigure('Visible', 'off');
            app.Identificationsettingsms1UIFigure.Name = 'Identification settings (ms1)';
            app.Panel = uipanel(app.Identificationsettingsms1UIFigure);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.DatabaseDropDownLabel = uilabel(app.Panel);
            app.DatabaseDropDownLabel.Text = 'Database';
            app.DatabaseDropDown = uidropdown(app.Panel);
            app.DatabaseDropDown.Items = {'Select database'};
            app.DatabaseDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DatabaseDropDownOpening, true);
            app.DatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @DatabaseDropDownValueChanged, true);
            app.DatabaseDropDown.Value = 'Select database';
            app.mzerrorppmLabel = uilabel(app.Panel);
            app.mzerrorppmLabel.Text = 'm/z error (ppm)';
            app.Precursorthreshold01Label = uilabel(app.Panel);
            app.Precursorthreshold01Label.Text = 'Precursor threshold (0-1)';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Limits = [0 1];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.IonizactionofthesearchDropDown = uidropdown(app.Panel);
            app.IonizactionofthesearchDropDown.Items = {'positive', 'negative'};
            app.IonizactionofthesearchDropDown.ValueChangedFcn = createCallbackFcn(app, @IonizactionofthesearchDropDownValueChanged, true);
            app.IonizactionofthesearchDropDown.Value = 'positive';
            app.IonizactionofthesearchDropDownLabel = uilabel(app.Panel);
            app.IonizactionofthesearchDropDownLabel.Text = {'Ionizaction of '; 'the search'};
            % Show the figure after all components are created
            app.Identificationsettingsms1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = identification_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Identificationsettingsms1UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Identificationsettingsms1UIFigure)
        end
    end
end