classdef identification_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Identificationsettingsms1_pms2_pms1_nms2_nUIFigure  matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        OKButton                        matlab.ui.control.Button
        EditField_4                     matlab.ui.control.NumericEditField
        MS2mzerrorppmLabel              matlab.ui.control.Label
        IonizactionofthesearchDropDown  matlab.ui.control.DropDown
        IonizactionofthesearchDropDownLabel  matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        Scorethreshold01000Label        matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Precursorthreshold01Label       matlab.ui.control.Label
        MS1mzerrorppmLabel              matlab.ui.control.Label
        DatabaseDropDown                matlab.ui.control.DropDown
        DatabaseDropDownLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        selectedVal_i3 = {};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value_db = evalin('base','ROIMCR_identification.settings_identification.databasename;');
            if isempty(value_db) 
               app.DatabaseDropDown.Value = 'Select database';
            else
                app.DatabaseDropDown.Items = {value_db};
                app.DatabaseDropDown.Value = value_db;
            end

            value_i = evalin('base','ROIMCR_identification.settings_identification.ionization;');
            if value_i == 1
                value = 'positive';
            elseif value_i == 2
                value = 'negative';
            end

            app.IonizactionofthesearchDropDown.Value = {value};

            app.EditField.Value = evalin('base','ROIMCR_identification.settings_identification.error_ms1;');
            app.EditField_4.Value = evalin('base','ROIMCR_identification.settings_identification.error_ms2;');
            app.EditField_2.Value = evalin('base','ROIMCR_identification.settings_identification.precursor;');
            app.EditField_3.Value = evalin('base','ROIMCR_identification.settings_identification.score;');
        end

        % Drop down opening function: DatabaseDropDown
        function DatabaseDropDownOpening(app, event)
            vars = evalin('base', 'whos');
            vars = vars(strcmp({vars.class}, 'struct'),:);
            cell_array = cell(size(vars));
            cell_array{1} = 'Select database';
            for i=2:length(vars)+1
                cell_array{i} = vars(i-1).name;
            end
            
            %Delete StartUp variable
            cell_array = erase(cell_array,'ROIMCR_identification');
            emptyCells = cellfun(@isempty, cell_array);
            nonEmptyCells = ~emptyCells;
            cell_array = cell_array(nonEmptyCells);

            app.DatabaseDropDown.Items = cell_array;
        end

        % Value changed function: IonizactionofthesearchDropDown
        function IonizactionofthesearchDropDownValueChanged(app, event)
            ionization = app.IonizactionofthesearchDropDown.Value;
            
            if strcmp(ionization,'positive')
                ionization = 1;
            elseif strcmp(ionization,'negative')
                ionization = 2;
            end

            assignin('base','ionization',ionization);
            evalin('base','ROIMCR_identification.settings_identification.ionization=ionization;'); 
            evalin('base','clear ionization');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            error_ms1 = app.EditField.Value;
            
            assignin('base','error_ms1',error_ms1);
            evalin('base','ROIMCR_identification.settings_identification.error_ms1=error_ms1;'); 
            evalin('base','clear error_ms1');
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            error_ms2 = app.EditField_4.Value;
            
            assignin('base','error_ms2',error_ms2);
            evalin('base','ROIMCR_identification.settings_identification.error_ms2=error_ms2;'); 
            evalin('base','clear error_ms2');
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            precursor = app.EditField_2.Value;
            
            assignin('base','precursor',precursor);
            evalin('base','ROIMCR_identification.settings_identification.precursor=precursor;'); 
            evalin('base','clear precursor');
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            score = app.EditField_3.Value;
            
            assignin('base','score',score);
            evalin('base','ROIMCR_identification.settings_identification.score=score;'); 
            evalin('base','clear score');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            Figurename = app.Identificationsettingsms1_pms2_pms1_nms2_nUIFigure ;

            datb = evalin('base','ROIMCR_identification.settings_identification.databasename;');
            ioni = evalin('base','ROIMCR_identification.settings_identification.ionization;');
            if ioni == 1
                ioni = 'positive';
            elseif ioni == 2
                ioni = 'negative';
            end
            err_1 = evalin('base','ROIMCR_identification.settings_identification.error_ms1;');
            err_2 = evalin('base','ROIMCR_identification.settings_identification.error_ms2;');
            p_ion = evalin('base','ROIMCR_identification.settings_identification.precursor;');
            sco = evalin('base','ROIMCR_identification.settings_identification.score;');

            selectedVal_i3{1} = datb;
            selectedVal_i3{2} = ioni;
            selectedVal_i3{3} = num2str(err_1);
            selectedVal_i3{4} = num2str(err_2);
            selectedVal_i3{5} = num2str(p_ion);
            selectedVal_i3{6} = num2str(sco);
            setappdata(0, 'selectedVal_i3', selectedVal_i3);

            close(Figurename)
        end

        % Value changed function: DatabaseDropDown
        function DatabaseDropDownValueChanged(app, event)
            database = app.DatabaseDropDown.Value;
            database_name = app.DatabaseDropDown.Value;

            %store the database and its name 
            assignin('base','database_name',database_name)
            evalin('base','ROIMCR_identification.settings_identification.databasename=database_name;') 
            evalin('base','clear database_name');

            database = evalin('base', database);

            assignin('base','database_selected',database)
            evalin('base','ROIMCR_identification.settings_identification.database=database_selected;') 
            evalin('base','clear database_selected');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Identificationsettingsms1_pms2_pms1_nms2_nUIFigure = uifigure('Visible', 'off');
            app.Identificationsettingsms1_pms2_pms1_nms2_nUIFigure.Name = 'Identification settings (ms1_p+ms2_p + ms1_n+ms2_n)';
            app.Panel = uipanel(app.Identificationsettingsms1_pms2_pms1_nms2_nUIFigure);
            app.DatabaseDropDownLabel = uilabel(app.Panel);
            app.DatabaseDropDownLabel.Text = 'Database';
            app.DatabaseDropDown = uidropdown(app.Panel);
            app.DatabaseDropDown.Items = {'Select database'};
            app.DatabaseDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DatabaseDropDownOpening, true);
            app.DatabaseDropDown.ValueChangedFcn = createCallbackFcn(app, @DatabaseDropDownValueChanged, true);
            app.DatabaseDropDown.Value = 'Select database';
            app.MS1mzerrorppmLabel = uilabel(app.Panel);
            app.MS1mzerrorppmLabel.Text = 'MS1 m/z error (ppm)';
            app.Precursorthreshold01Label = uilabel(app.Panel);
            app.Precursorthreshold01Label.Text = 'Precursor threshold (0-1)';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Limits = [0 1];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Scorethreshold01000Label = uilabel(app.Panel);
            app.Scorethreshold01000Label.Text = 'Score threshold (0-1000)';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Limits = [0 1000];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.IonizactionofthesearchDropDownLabel = uilabel(app.Panel);
            app.IonizactionofthesearchDropDownLabel.Text = {'Ionizaction of '; 'the search'};
            app.IonizactionofthesearchDropDown = uidropdown(app.Panel);
            app.IonizactionofthesearchDropDown.Items = {'positive', 'negative'};
            app.IonizactionofthesearchDropDown.ValueChangedFcn = createCallbackFcn(app, @IonizactionofthesearchDropDownValueChanged, true);
            app.IonizactionofthesearchDropDown.Value = 'positive';
            app.MS2mzerrorppmLabel = uilabel(app.Panel);
            app.MS2mzerrorppmLabel.Text = 'MS2 m/z error (ppm)';
            app.EditField_4 = uieditfield(app.Panel, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.OKButton = uibutton(app.Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.Identificationsettingsms1_pms2_pms1_nms2_nUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = identification_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Identificationsettingsms1_pms2_pms1_nms2_nUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Identificationsettingsms1_pms2_pms1_nms2_nUIFigure)
        end
    end
end