classdef ms2_representation_comp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSidentMS2ionsUIFigure  matlab.ui.Figure
        MS2ionsPanel            matlab.ui.container.Panel
        UITable_ms2             matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            component = evalin('base','ROIMCR_identification.visualization.component;');
            result_ident = evalin('base','ROIMCR_identification.ROIMCR_identification;');
            precursor = evalin('base','ROIMCR_identification.visualization.precursor;');
            row = evalin('base','ROIMCR_identification.visualization.compound_ms2;');

            ms2 = result_ident(component).RESULTS(precursor).IDENTIFICATIONS(row).MS2;
            ms2(:,2) = int16(ms2(:,2));
            
            assignin('base','ms2',ms2);
            evalin('base','ROIMCR_identification.visualization.ms2_compound = ms2;');
            evalin('base','clear ms2');

            app.UITable_ms2.Data = evalin('base','ROIMCR_identification.visualization.ms2_compound;');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MSidentMS2ionsUIFigure = uifigure('Visible', 'off');
            app.MSidentMS2ionsUIFigure.Name = 'MSident: MS2 ions';
            app.MS2ionsPanel = uipanel(app.MSidentMS2ionsUIFigure);
            app.MS2ionsPanel.Title = 'MS2 ions';
            app.UITable_ms2 = uitable(app.MS2ionsPanel);
            app.UITable_ms2.ColumnName = {'m/z'; 'Intensity'};
            app.UITable_ms2.RowName = {};
            % Show the figure after all components are created
            app.MSidentMS2ionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ms2_representation_comp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSidentMS2ionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSidentMS2ionsUIFigure)
        end
    end
end