classdef mzROI_extraction_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ExtractionofmzROIsfromSmatrixms1Panel  matlab.ui.container.Panel
        OKButton                   matlab.ui.control.Button
        EditField                  matlab.ui.control.NumericEditField
        Intensitythreshold01Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres=0.1;');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            intensity_thres = app.EditField.Value;
            
            assignin('base','intensity_thres',intensity_thres);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres=intensity_thres;');
            evalin('base','clear intensity_thres');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            sopt = evalin('base','ROIMCR_identification.matrix.st;');
            mzROI = evalin('base', 'ROIMCR_identification.matrix.mzROI;');
            threshold = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
            results = mz_extraction_roimcr_1(sopt,mzROI,threshold);

            assignin('base','results',results);
            evalin('base','ROIMCR_identification.ROIMCR_signals=results;');
            evalin('base','clear results');

            Figurename = app.UIFigure ;
            close(Figurename)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExtractionofmzROIsfromSmatrixms1Panel = uipanel(app.UIFigure);
            app.ExtractionofmzROIsfromSmatrixms1Panel.Title = 'Extraction of mzROIs from S matrix (ms1)';
            app.Intensitythreshold01Label = uilabel(app.ExtractionofmzROIsfromSmatrixms1Panel);
            app.Intensitythreshold01Label.Text = 'Intensity threshold (0-1): ';
            app.EditField = uieditfield(app.ExtractionofmzROIsfromSmatrixms1Panel, 'numeric');
            app.EditField.Limits = [0 1];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.1;
            app.OKButton = uibutton(app.ExtractionofmzROIsfromSmatrixms1Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mzROI_extraction_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end