classdef mzROI_extraction_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ExtractionofmzROIsfromSTmatrixms1ms2Panel  matlab.ui.container.Panel
        EditField_3                matlab.ui.control.NumericEditField
        LimitLabel                 matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        Intensitythreshold01normalizedLabel  matlab.ui.control.Label
        OKButton                   matlab.ui.control.Button
        EditField                  matlab.ui.control.NumericEditField
        Intensitythreshold01Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres=0.1;');
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm=0.1;');
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.limit=[];');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            intensity_thres = app.EditField.Value;
            
            assignin('base','intensity_thres',intensity_thres);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres=intensity_thres;');
            evalin('base','clear intensity_thres');
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            intensity_thresh_norm = app.EditField_2.Value;
            
            assignin('base','intensity_thres_norm',intensity_thresh_norm);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm=intensity_thres_norm;');
            evalin('base','clear intensity_thres_norm');
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            limits = app.EditField_3.Value;
            
            assignin('base','limits',limits);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits=limits;');
            evalin('base','clear limits');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            sopt = evalin('base','ROIMCR_identification.matrix.st;');
            mzROI = evalin('base', 'ROIMCR_identification.matrix.mzROI;');
            threshold = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
            threshold_norm = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm;');
            limits = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits;');
            %Meter pruebas de errores
            %try
            results = mz_extraction_roimcr_2(sopt,mzROI,limits,threshold, threshold_norm);

            assignin('base','results',results);
            evalin('base','ROIMCR_identification.ROIMCR_signals=results;');
            evalin('base','clear results');

            Figurename = app.UIFigure ;
            close(Figurename)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExtractionofmzROIsfromSTmatrixms1ms2Panel = uipanel(app.UIFigure);
            app.ExtractionofmzROIsfromSTmatrixms1ms2Panel.Title = 'Extraction of mzROIs from ST matrix (ms1+ms2)';
            app.Intensitythreshold01Label = uilabel(app.ExtractionofmzROIsfromSTmatrixms1ms2Panel);
            app.Intensitythreshold01Label.Text = 'Intensity threshold (0-1): ';
            app.EditField = uieditfield(app.ExtractionofmzROIsfromSTmatrixms1ms2Panel, 'numeric');
            app.EditField.Limits = [0 1];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.1;
            app.OKButton = uibutton(app.ExtractionofmzROIsfromSTmatrixms1ms2Panel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Intensitythreshold01normalizedLabel = uilabel(app.ExtractionofmzROIsfromSTmatrixms1ms2Panel);
            app.Intensitythreshold01normalizedLabel.Text = {'Intensity threshold (0-1)'; '(normalized) '};
            app.EditField_2 = uieditfield(app.ExtractionofmzROIsfromSTmatrixms1ms2Panel, 'numeric');
            app.EditField_2.Limits = [0 1];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 0.1;
            app.Label = uilabel(app.ExtractionofmzROIsfromSTmatrixms1ms2Panel);
            app.Label.Text = ':';
            app.LimitLabel = uilabel(app.ExtractionofmzROIsfromSTmatrixms1ms2Panel);
            app.LimitLabel.Text = 'Limit:';
            app.EditField_3 = uieditfield(app.ExtractionofmzROIsfromSTmatrixms1ms2Panel, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mzROI_extraction_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end