classdef mzROI_extraction_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel  matlab.ui.container.Panel
        EditField_5                matlab.ui.control.NumericEditField
        Limit3Label                matlab.ui.control.Label
        EditField_4                matlab.ui.control.NumericEditField
        Limit2Label                matlab.ui.control.Label
        EditField_3                matlab.ui.control.NumericEditField
        Limit1Label                matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        Intensitythreshold01normalizedLabel  matlab.ui.control.Label
        OKButton                   matlab.ui.control.Button
        EditField                  matlab.ui.control.NumericEditField
        Intensitythreshold01Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres=0.1;');
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm=0.1;');
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.limit=[];');
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            intensity_thres = app.EditField.Value;
            
            assignin('base','intensity_thres',intensity_thres);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres=intensity_thres;');
            evalin('base','clear intensity_thres');
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            intensity_thresh_norm = app.EditField_2.Value;
            
            assignin('base','intensity_thres',intensity_thresh_norm);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm=intensity_thres;');
            evalin('base','clear intensity_thres_norm');
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            limit_1 = app.EditField_3.Value;
            
            assignin('base','limits',limit_1);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits=limits;');
            evalin('base','clear limits');
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            limits = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits');
            value = app.EditField_4.Value;
            limits = [limits,value];
            limits = sort(limits);
            
            assignin('base','limits',limits);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits=limits;');
            evalin('base','clear limits');
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            limits = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits')
            value = app.EditField_5.Value;
            limits = [limits,value];
            limits = sort(limits);
            
            assignin('base','limits',limits);
            evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits=limits;');
            evalin('base','clear limits');
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            sopt = evalin('base','ROIMCR_identification.matrix.st;');
            mzROI = evalin('base', 'ROIMCR_identification.matrix.mzROI;');
            threshold = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres;');
            threshold_norm = evalin('base','ROIMCR_identification.settings_ROIMCRresults.intensity_thres_norm;');
            limits = evalin('base','ROIMCR_identification.settings_ROIMCRresults.limits;');
            %Meter pruebas de errores
            %try
            results = mz_extraction_roimcr_3(sopt,mzROI,limits,threshold, threshold_norm);

            assignin('base','results',results);
            evalin('base','ROIMCR_identification.ROIMCR_signals=results;');
            evalin('base','clear results');

            Figurename = app.UIFigure ;
            close(Figurename)
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel = uipanel(app.UIFigure);
            app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel.Title = 'Extraction of mzROIs from ST matrix (ms1_p+ms2_p + ms1_n+ms2_n)';
            app.Intensitythreshold01Label = uilabel(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel);
            app.Intensitythreshold01Label.Text = 'Intensity threshold (0-1):';
            app.EditField = uieditfield(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel, 'numeric');
            app.EditField.Limits = [0 1];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.1;
            app.OKButton = uibutton(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Intensitythreshold01normalizedLabel = uilabel(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel);
            app.Intensitythreshold01normalizedLabel.Text = {'Intensity threshold (0-1)'; '(normalized) '};
            app.EditField_2 = uieditfield(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel, 'numeric');
            app.EditField_2.Limits = [0 1];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 0.1;
            app.Label = uilabel(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel);
            app.Label.Text = ':';
            app.Limit1Label = uilabel(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel);
            app.Limit1Label.Text = 'Limit 1:';
            app.EditField_3 = uieditfield(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel, 'numeric');
            app.EditField_3.Limits = [0 Inf];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.Limit2Label = uilabel(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel);
            app.Limit2Label.Text = 'Limit 2:';
            app.EditField_4 = uieditfield(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.Limit3Label = uilabel(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel);
            app.Limit3Label.Text = 'Limit 3:';
            app.EditField_5 = uieditfield(app.ExtractionofmzROIsfromSTmatrixms1_pms2_pms1_nms2_nPanel, 'numeric');
            app.EditField_5.Limits = [0 Inf];
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mzROI_extraction_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end