classdef prueba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureSepOne                    matlab.ui.Figure
        ROIsevaluationButton            matlab.ui.control.Button
        RunROIButton                    matlab.ui.control.Button
        ROIalgorithmparametersPanel     matlab.ui.container.Panel
        dropMZcalc                      matlab.ui.control.DropDown
        editOcurrences                  matlab.ui.control.EditField
        listMZunits                     matlab.ui.control.ListBox
        editMZerror                     matlab.ui.control.EditField
        editFactor                      matlab.ui.control.EditField
        editSignal                      matlab.ui.control.EditField
        textMZcalc                      matlab.ui.control.Label
        textOcurrences                  matlab.ui.control.Label
        textMZunits                     matlab.ui.control.Label
        textMZerror                     matlab.ui.control.Label
        textFactor                      matlab.ui.control.Label
        textSignal                      matlab.ui.control.Label
        OptionsPanel                    matlab.ui.container.Panel
        editHighTime                    matlab.ui.control.EditField
        toLabel                         matlab.ui.control.Label
        editLowTime                     matlab.ui.control.EditField
        SelectelutionprofilesfromLabel  matlab.ui.control.Label
        checkCut                        matlab.ui.control.CheckBox
        editTargError                   matlab.ui.control.EditField
        editTargMZ                      matlab.ui.control.EditField
        AllowederrorDaLabel             matlab.ui.control.Label
        VariablenamewithmzvaluesLabel   matlab.ui.control.Label
        checkTargeted                   matlab.ui.control.CheckBox
        editHighMZ                      matlab.ui.control.EditField
        editLowMZ                       matlab.ui.control.EditField
        HighmzLabel                     matlab.ui.control.Label
        LowmzLabel                      matlab.ui.control.Label
        checkMZfilter                   matlab.ui.control.CheckBox
        check2D                         matlab.ui.control.CheckBox
        SummaryPanel                    matlab.ui.container.Panel
        buttonTIC                       matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        textMZrangeHigh                 matlab.ui.control.Label
        textMZrangeLow                  matlab.ui.control.Label
        mzrangeLabel                    matlab.ui.control.Label
        textMaxInt                      matlab.ui.control.Label
        MSmaximumintensityLabel         matlab.ui.control.Label
        textNoElution                   matlab.ui.control.Label
        NoofelutiontimesisLabel         matlab.ui.control.Label
        FiledetailsPanel                matlab.ui.container.Panel
        ListTypes                       matlab.ui.control.ListBox
        textName                        matlab.ui.control.Label
        buttonSelect                    matlab.ui.control.Button
        textFilename                    matlab.ui.control.Label
        buttonImport                    matlab.ui.control.Button
        textType                        matlab.ui.control.Label
        SeparationssinglefileanalysisLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','importMSv.load.netCDF=1;');  % by default the selected option
            evalin('base','importMSv.load.mzXML=0;');
            evalin('base','importMSv.load.mzXMLAgilent=0;');
            evalin('base','importMSv.load.file=[];');
            evalin('base','importMSv.data=[];');  
            evalin('base','importMSv.msroi.ROI_units=1;');
            evalin('base','importMSv.msroi.all=1;');         
            evalin('base',['importMSv.summary.totalFiles=1;']);
        end

        % Value changed function: ListTypes
        function ListTypesValueChanged(app, event)
            mzFile = app.ListTypes.Value;
            
            if strcmp(mzFile,'netCDF')
                evalin('base','importMSv.load.netCDF=1;');
                evalin('base','importMSv.load.mzXML=0;');
                evalin('base','importMSv.load.mzXMLAgilent=0;');
            elseif strcmp(mzFile,'mzXML')
                evalin('base','importMSv.load.netCDF=0;');
                evalin('base','importMSv.load.mzXML=1;');
                evalin('base','importMSv.load.mzXMLAgilent=0;');
                
            else
                evalin('base','importMSv.load.netCDF=0;');
                evalin('base','importMSv.load.mzXML=0;');
                evalin('base','importMSv.load.mzXMLAgilent=1;');
            end
            
        end

        % Button pushed function: buttonSelect
        function buttonSelectButtonPushed(app, event)
            if (evalin('base','importMSv.load.netCDF')==1)
                [filename, pathname, filterindex] = uigetfile('*.cdf', 'Pick a netCDF MS file');
            elseif (evalin('base','importMSv.load.mzXML')==1)
                [filename, pathname, filterindex] = uigetfile('*.mzXML', 'Pick a mzXML MS file');
            elseif (evalin('base','importMSv.load.mzXMLAgilent')==1)
                [filename, pathname, filterindex] = uigetfile('*.mzXML', 'Pick a mzXML MS file');
            end
            
            app.textName.Text = filename;
            assignin('base','fileSelected',filename);
            evalin('base','importMSv.load.file=fileSelected;');
            evalin('base','clear fileSelected;');
            
            assignin('base','pathnameSelected',pathname);
            evalin('base','importMSv.load.pathName=pathnameSelected;');
            evalin('base','clear pathnameSelected;');
            
            app.buttonImport.Enable = 'on';
        end

        % Button pushed function: buttonImport
        function buttonImportButtonPushed(app, event)
            
            %test errors
            file=evalin('base','importMSv.load.file;');
            pathname=evalin('base','importMSv.load.pathName;');
            valnetCDF=evalin('base','importMSv.load.netCDF;');
            valmzXML=evalin('base','importMSv.load.mzXML;');
            valmzXMLAgilent=evalin('base','importMSv.load.mzXMLAgilent;');
            
            % run
            
            [pathstr,name_short,ext] = fileparts(file)
            
            if valnetCDF==1
                eval(['out_',name_short,'=mzcdfread(''',pathname,'\',file,''');']);
            elseif valmzXML==1
                eval(['out_',name_short,'=mzxmlread(''',pathname,'\',file,''');']);
            elseif valmzXMLAgilent==1
                eval(['out_',name_short,'=mzxmlreadAgilent(''',pathname,'\',file,''');']);
            end
            
            peaks_name=['peaks_',name_short];
            time_name=['time_',name_short];
            tic_name=['tic_',name_short];
            
            if valnetCDF==1
                eval(['[',peaks_name,',',time_name,']=mzcdf2peaks(out_',name_short,');']);
            elseif valmzXML==1
                eval(['[',peaks_name,',',time_name,']=mzxml2peaks(out_',name_short,');']);
            elseif valmzXMLAgilent==1
                eval(['[',peaks_name,',',time_name,']=mzxml2peaks(out_',name_short,');']);
            end
            eval(['clear out_',name_short]);
            
            no_times=length(eval(time_name));
            assignin('base',peaks_name,eval(peaks_name));
            assignin('base',time_name,eval(time_name));
            
            assignin('base','name_short',name_short);
            evalin('base','importMSv.summary.name_short=name_short;');
            
            assignin('base','no_times',no_times);
            evalin('base','importMSv.summary.no_times=no_times;');
            
            maximInt=0;
            lowMZ=0;
            highMZ=0;
            T=[];
            for j=1:no_times;
                % int
                x=eval(peaks_name);
                A=x{j}(:,2);
                B=max(A);
                T(j)=sum(A);
                if B>maximInt
                    maximInt=B;
                end
                
                % mass
                mzr=[];
                mzr=x{j}(:,1);
                mzL=min(mzr);
                mzH=max(mzr);
                if j==1
                    lowMZ=mzL;
                    highMZ=mzH;
                else
                    if mzL<lowMZ
                        lowMZ=mzL;
                    end
                    if mzH>highMZ
                        highMZ=mzH;
                    end
                end
                
                
            end
      
            assignin('base',tic_name,T);

            % summary
            assignin('base','el_times',no_times);
            evalin('base','importMSv.summary.el_times=el_times;');
            app.textNoElution.Text = num2str(no_times);
            evalin('base','clear name_short no_times el_times');
            
            assignin('base','maximInt',maximInt);
            evalin('base','importMSv.summary.maximInt=maximInt;');
            evalin('base','clear maximInt');
            
            assignin('base','lowMZ',lowMZ);
            evalin('base','importMSv.summary.lowMZ=lowMZ;');
            evalin('base','clear lowMZ');
            
            assignin('base','highMZ',highMZ);
            evalin('base','importMSv.summary.highMZ=highMZ;');
            evalin('base','clear highMZ');
            
            assignin('base','tic_chrom',T);
            evalin('base','importMSv.summary.tic=tic_chrom;');
            evalin('base','clear tic_chrom');
            
            disp(' ');
            disp(' *********************************************************');
            
            app.textMaxInt.Text = num2str(maximInt);
            app.textMZrangeLow.Text = num2str(lowMZ);
            app.textMZrangeHigh.Text = num2str(highMZ);
            
            % activate options
            app.buttonTIC.Enable = 'on';
            app.checkCut.Enable = 'on';
            app.checkMZfilter.Enable = 'on';
            app.checkTargeted.Enable = 'on';
            app.check2D.Enable = 'on';
            
            % activate ROI parameters
            app.editSignal.Enable = 'on';
            app.editFactor.Enable = 'on';
            app.editMZerror.Enable = 'on';
            app.listMZunits.Enable = 'on';
            app.dropMZcalc.Enable = 'on';
            app.editOcurrences.Enable = 'on';
            app.RunROIButton.Enable = 'on';
            
            % set option values
            evalin('base','importMSv.options.mzTargeted = 0;');
            evalin('base','importMSv.options.mzFilter = 0;');
            evalin('base','importMSv.options.chrom2D = 0;');
            evalin('base','importMSv.options.cutTime = 0;');
            
            evalin('base',['importMSv.msroi.ROI_units=','''daltons'';']);
            evalin('base',['importMSv.msroi.weighted=','''default: mean'';']);
            
            
        end

        % Button pushed function: buttonTIC
        function buttonTICButtonPushed(app, event)
            figure;
            tic=evalin('base','importMSv.summary.tic');
            plot(tic)
            xlabel('Channels');
            ylabel('Intensity (a.u.)');
            
        end

        % Value changed function: checkMZfilter
        function checkMZfilterValueChanged(app, event)
            value = app.checkMZfilter.Value;
            
            if value==1
                evalin('base','importMSv.options.mzFilter=1;');
                app.editLowMZ.Enable = 'on';
                app.editHighMZ.Enable = 'on';
                evalin('base','importMSv.options.lowMZ=[];');
                evalin('base','importMSv.options.highMZ=[];');
            else
                evalin('base','importMSv.options.mzFilter=0;');
                evalin('base',['importMSv.options=rmfield(importMSv.options,','''lowMZ''',');']);
                evalin('base',['importMSv.options=rmfield(importMSv.options,','''highMZ''',');']);
                app.editLowMZ.Enable = 'off';
                app.editLowMZ.Value = '';
                app.editHighMZ.Enable = 'off';
                app.editHighMZ.Value = '';
            end
            
        end

        % Value changed function: editLowMZ
        function editLowMZValueChanged(app, event)
       
            lowMZ = str2double(app.editLowMZ.Value);
            assignin('base','lowMZ',lowMZ);
            evalin('base','importMSv.options.lowMZ=lowMZ;');
            evalin('base','clear lowMZ;');
            
        end

        % Value changed function: editHighMZ
        function editHighMZValueChanged(app, event)
            highMZ = str2double(app.editHighMZ.Value);
            assignin('base','highMZ',highMZ);
            evalin('base','importMSv.options.highMZ=highMZ;');
            evalin('base','clear highMZ;');
            
        end

        % Value changed function: checkTargeted
        function checkTargetedValueChanged(app, event)
            value = app.checkTargeted.Value;
            
            if value==1
                evalin('base','importMSv.options.mzTargeted=1;');
                app.editTargMZ.Enable = 'on';
                app.editTargError.Enable = 'on';
                evalin('base','importMSv.options.target=[];');
                evalin('base','importMSv.options.targError=[];');
                
            else
                evalin('base','importMSv.options.mzTargeted=0;');
                evalin('base',['importMSv.options=rmfield(importMSv.options,','''target''',');']);
                evalin('base',['importMSv.options=rmfield(importMSv.options,','''targError''',');']);
                app.editTargMZ.Enable = 'off';
                app.editTargMZ.Value = ' ';
                app.editTargError.Enable = 'off';
                app.editTargError.Value = '';
            end
            
            
        end

        % Value changed function: editTargMZ
        function editTargMZValueChanged(app, event)
            
            target = app.editTargMZ.Value;
            target=evalin('base',target);
            assignin('base','target',target);
            evalin('base','importMSv.options.target=target;');
            evalin('base','clear target');
            
        end

        % Value changed function: editTargError
        function editTargErrorValueChanged(app, event)
            targError = str2double(app.editTargError.Value);
            assignin('base','targError',targError);
            evalin('base','importMSv.options.targError=targError;');
            evalin('base','clear targError;');
            
        end

        % Value changed function: check2D
        function check2DValueChanged(app, event)
            value = app.check2D.Value;
            
            if value==1
                evalin('base','importMSv.options.chrom2D=1;');
            else
                evalin('base','importMSv.options.chrom2D=0;');
            end
        end

        % Value changed function: checkCut
        function checkCutValueChanged(app, event)
            value = app.checkCut.Value;
            
            if value==1
                evalin('base','importMSv.options.cutTime=1;');
                app.editLowTime.Enable = 'on';
                app.editHighTime.Enable = 'on';
                evalin('base','importMSv.options.lowTime=[];');
                evalin('base','importMSv.options.highTime=[];');
            else
                evalin('base','importMSv.options.cutTime=0;');
                evalin('base',['importMSv.options=rmfield(importMSv.options,','''lowTime''',');']);
                evalin('base',['importMSv.options=rmfield(importMSv.options,','''highTime''',');']);
                
                app.editLowTime.Enable = 'off';
                app.editLowTime.Value = ' ';
                app.editHighTime.Enable = 'off';
                app.editHighTime.Value = '';
            end

        end

        % Value changed function: editLowTime
        function editLowTimeValueChanged(app, event)
        
            lowTime = str2double(app.editLowTime.Value);
            assignin('base','lowTime',lowTime);
            evalin('base','importMSv.options.lowTime=lowTime;');
            evalin('base','clear lowTime');
            
        end

        % Value changed function: editHighTime
        function editHighTimeValueChanged(app, event)
            highTime = str2double(app.editHighTime.Value);
            assignin('base','highTime',highTime);
            evalin('base','importMSv.options.highTime=highTime;');
            evalin('base','clear highTime;');
            
        end

        % Value changed function: editSignal
        function editSignalValueChanged(app, event)
            
            threshold = str2double(app.editSignal.Value);
            assignin('base','ROI_threshold',threshold);
            evalin('base','importMSv.msroi.ROI_threshold=ROI_threshold;');
            evalin('base','clear ROI_threshold;');
            
        end

        % Value changed function: editFactor
        function editFactorValueChanged(app, event)
            
            factorThresh=str2double(app.editFactor.Value);
            assignin('base','factorThresh',factorThresh);
            evalin('base','importMSv.msroi.factorThresh=factorThresh;');
            evalin('base','clear factorThresh;');
            
        end

        % Value changed function: editMZerror
        function editMZerrorValueChanged(app, event)
            error=str2double(app.editMZerror.Value);
            assignin('base','ROI_error',error);
            evalin('base','importMSv.msroi.ROI_error=ROI_error;');
            evalin('base','clear ROI_error;');
            
        end

        % Value changed function: listMZunits
        function listMZunitsValueChanged(app, event)
            units = app.listMZunits.Value;
            assignin('base','ROI_units',units);
            evalin('base','importMSv.msroi.ROI_units=ROI_units;');
            evalin('base','clear ROI_units');
            
        end

        % Value changed function: dropMZcalc
        function dropMZcalcValueChanged(app, event)
            mzd = app.dropMZcalc.Value;
            assignin('base','mzd',mzd);
            evalin('base','importMSv.msroi.weighted=mzd;');
            evalin('base','clear mzd');
            
        end

        % Value changed function: editOcurrences
        function editOcurrencesValueChanged(app, event)
            occur=str2double(app.editOcurrences.Value);
            assignin('base','ROI_occur',occur);
            evalin('base','importMSv.msroi.ROI_occur=ROI_occur;');
            evalin('base','clear ROI_occur;');
            
        end

        % Button pushed function: RunROIButton
        function RunROIButtonPushed(app, event)
            
            name_short=evalin('base','importMSv.summary.name_short');
            peaks=evalin('base',['peaks_',name_short]);
            time=evalin('base',['time_',name_short]);
            
            % evaluate all or some times
            
            if (evalin('base','importMSv.options.cutTime;')==0);
                % nothing to do
            else
                times_from=evalin('base','importMSv.options.lowTime;');
                times_to=evalin('base','importMSv.options.highTime;');
                time=time(times_from:times_to);
                peaks=peaks(times_from:times_to);
            end
            
            % inits
            thresh=evalin('base','importMSv.msroi.ROI_threshold');
            mzerror=evalin('base','importMSv.msroi.ROI_error');
            minroi=evalin('base','importMSv.msroi.ROI_occur');
            units=evalin('base','importMSv.msroi.ROI_units');
            factorThresh=evalin('base','importMSv.msroi.factorThresh');
            weighted=evalin('base','importMSv.msroi.weighted');
            nrows=length(peaks);
            nruns=1;
            
            mzTargeted=evalin('base','importMSv.options.mzTargeted;');
            mzFilter=evalin('base','importMSv.options.mzFilter;');
            
            if mzTargeted==1
                target=evalin('base','importMSv.options.target;');
                targError=evalin('base','importMSv.options.targError;');
                
                tmz=[];
                for t=1:length(target);
                    tmz(t,:)=[target(t)-targError;target(t)+targError];
                end
            end

            disp(' ');
            disp(' *********************************************************');
            disp('number of spectra (elution times) to process is: ');disp(nrows)
            mzroi=[];
            MSroi=[];
            roicell{1,1}=[];
            roicell{1,2}=[];
            roicell{1,3}=[];
            roicell{1,4}=[];
            nmzroi=1;
            errorSort=0;
            
            % looking for mzroi values
            
            for irow=1:nrows,
                disp('MS spectrum (elution time) being processed is: ');disp(irow)
                A=cell2mat(peaks(irow,1));
                A=double(A);
                
                if length(A)>0
                    
                    if mzTargeted==1
                        B=A;
                        A=[];
                        for tt=1:length(target);
                            C=B(find(B(:,1)>=tmz(tt,1) & B(:,1)<=tmz(tt,2)),:);
                            A=[A;C];
                        end
                        ipeak=find(A(:,2)>thresh);
                    else
                        if mzFilter==1
                            lowMZ=evalin('base','importMSv.options.lowMZ;');
                            highMZ=evalin('base','importMSv.options.highMZ;');
                            if lowMZ>highMZ;
                                if errorSort==0;warndlg('Error in the mz range. Values are changed');errorSort=1;end
                                axmz=lowMZ;lowMZ=highMZ;highMZ=axmz;
                            end
                            
                            A=A(find(A(:,1)>=lowMZ & A(:,1)<=highMZ),:);
                            ipeak=find(A(:,2)>thresh);
                        else
                            ipeak=find(A(:,2)>thresh);
                        end
                        
                    end
                    
                    
                    if isfinite(ipeak)
                        mz=A(ipeak,1);
                        MS=A(ipeak,2);
                        
                        if irow==1,mzroi=mz(1);end
                        
                        nmz=size(mz);
                        
                        for i=1:nmz
                            
                            if strcmp(units,'daltons')
                                ieq=find(abs(mzroi-mz(i))<=mzerror);
                            elseif strcmp(units,'ppm')
                                delta=10^-6*mzerror*mz(i);
                                ieq=find(abs(mzroi-mz(i))<=delta);
                            end
                            
                            if isfinite(ieq)

                                for k=1:length(ieq);
                                    kieq=ieq(k);
                                    roicell{kieq,1}=[roicell{kieq,1},mz(i)];
                                    roicell{kieq,2}=[roicell{kieq,2},time(irow)];
                                    roicell{kieq,3}=[roicell{kieq,3},MS(i)];
                                    roicell{kieq,4}=[roicell{kieq,4},irow];
                                    
                                    if strcmp(weighted,'default: mean')
                                        roicell{kieq,5}=mean(roicell{kieq,1});
                                    elseif strcmp(weighted,'median')
                                        roicell{kieq,5}=median(roicell{kieq,1});
                                    elseif strcmp(weighted,'weighted')
                                        mzsint=[roicell{kieq,1}' roicell{kieq,3}'];
                                        roicell{kieq,5}=sum(prod(mzsint'))/sum(mzsint(:,2));
                                    elseif strcmp(weighted,'max')
                                        mzsint=[roicell{kieq,1}' roicell{kieq,3}'];
                                        [valorint,indexMax]=max(mzsint(:,2));
                                        roicell{kieq,5}=mzsint(indexMax,1);
                                    end
                                    
                                    mzroi(kieq)=roicell{kieq,5};
                                end
                                
                                
                            else
                                
                                disp('roi expansion'),
                                nmzroi=nmzroi+1
                                roicell{nmzroi,1}=mz(i);
                                roicell{nmzroi,2}=time(irow);
                                roicell{nmzroi,3}=MS(i);
                                roicell{nmzroi,4}=irow;
                                roicell{nmzroi,5}=mz(i);
                                mzroi(nmzroi)=mz(i);
                                
                            end
                            
                        end
                        
                    end
                    
                    
                end
            end
            
            % disp('initial mz ROI values'),disp(mzroi)
            disp('initial nr of ROI'),disp(nmzroi)
            [mzroi,isort]=sort(mzroi);
            
            % disp('mz ROI ordered values'),disp(mzroi)
            
            for i=1:nmzroi,for j=1:5,roicellsort{i,j}=roicell{isort(i),j};end,end
            roicell=roicellsort;
            disp('');disp('');
                        
            % last filter to combine ROIs < tolerance
            check_error=1;
            while check_error==1
                for i=2:length(mzroi);
                    error_mzroi(i-1)=abs(mzroi(i-1)-mzroi(i));
                end
                
                if strcmp(units,'daltons')
                    valor=find(error_mzroi<mzerror);
                elseif strcmp(units,'ppm')
                    delta=10^-6*mzerror*mzroi(i-1);
                    valor=find(error_mzroi<delta);
                end
                
                
                if isempty(valor);
                    check_error=0;
                else
                    roicell{valor(1),1}=[roicell{valor(1),1} roicell{valor(1)+1,1}];
                    roicell{valor(1),2}=[roicell{valor(1),2} roicell{valor(1)+1,2}];
                    roicell{valor(1),3}=[roicell{valor(1),3} roicell{valor(1)+1,3}];
                    roicell{valor(1),4}=[roicell{valor(1),4} roicell{valor(1)+1,4}];
                    if strcmp(weighted,'default: mean')
                        roicell{valor(1),5}=mean(roicell{valor(1),1});
                    elseif strcmp(weighted,'median')
                        roicell{valor(1),5}=median(roicell{valor(1),1});
                    elseif strcmp(weighted,'weighted')
                        mzsint=[roicell{valor(1),1}' roicell{valor(1),3}'];
                        roicell{kieq,5}=sum(prod(mzsint'))/sum(mzsint(:,2));
                    elseif strcmp(weighted,'max')
                        mzsint=[roicell{kieq,1}' roicell{kieq,3}'];
                        [valorint,indexMax]=max(mzsint(:,2));
                        roicell{kieq,5}=mzsint(indexMax,1);
                    end

                    roicell(valor(1),:)=[];
                    
                    mzroi(valor(1))=roicell{valor(1),5};
                    mzroi(valor(1)+1)=[];
                    clear valor error_mzroi;
                end
            end
            % *****************************
            
            % update nmzroi
            nmzroi=length(mzroi);
            
            % Now, filter those having a minimum number of elution times (minroi)
            % and a maximum value higher than thresh
            
            for i=1:nmzroi;
                if isempty(roicell{i,1}),roicell{i,1}=0;end
                numberroi(i)=length(roicell{i,1});
            end
            
            for i=1:nmzroi,
                if isempty(roicell{i,3}),roicell{i,3}=0;end
                maxroi(i)=max(roicell{i,3});
            end
            
            iroi=find(numberroi>minroi & maxroi>(thresh*factorThresh));
            
            mzroi=mzroi(iroi);
            nmzroi=length(mzroi);
            
            disp('final mz ROI values'),disp(mzroi)
            disp('final nr of ROI'),disp(nmzroi)
            disp('roicell')
            roicell=roicell(iroi,:)
            
            % Evaluation of MS values from roicell{nmzroi,3}
            % Now evaluating MS matrix only for thes mzroi values
            % Defining first the backgound
            
            MSroi=zeros(nrows,nmzroi);
            
            for i=1:nmzroi
                nval=length(roicell{i,4});
                for j=1:nval
                    irow=roicell{i,4}(j);
                    MSI=roicell{i,3}(j);
                    MSroi(irow,i)=MSroi(irow,i)+MSI;
                end
            end
            
            disp('displaying MS spectra only ROI mz values')
            figure
            bar(mzroi,sum(MSroi),mzerror)
            
            disp('displaying chromatograms only at ROI mz values')
            figure
            plot(time(1:nrows),MSroi)
            
            disp('final number of mz roi values considered has been: '),disp(nmzroi)

            threshC = strrep(num2str(thresh), '.', 'd');
            mzerrorC = strrep(num2str(mzerror), '.', 'd');
            minroiC = strrep(num2str(minroi), '.', 'd');
            factorC=strrep(num2str(factorThresh), '.', 'd');
            
            roicell_name=['roicell_',threshC,'_f',factorC,'_',mzerrorC,'_',minroiC];
            MSroi_name=['MSroi_',threshC,'_f',factorC,'_',mzerrorC,'_',minroiC];
            mzroi_name=['mzroi_',threshC,'_f',factorC,'_',mzerrorC,'_',minroiC];
            
            assignin('base',roicell_name,roicell);
            assignin('base',MSroi_name,MSroi);
            assignin('base',mzroi_name,mzroi);
            
            if (evalin('base','importMSv.msroi.all;')==0);
                times_name=['time_',threshC,'_',mzerrorC,'_',minroiC];
                assignin('base',times_name,time);
            end
            
            evalin('base',['importMSv.ROIplotGUI.current_roicell=',roicell_name,';']);
            evalin('base',['importMSv.ROIplotGUI.current_MSroi=',MSroi_name,';']);
            evalin('base',['importMSv.ROIplotGUI.current_mzroi=',mzroi_name,';']);
            
            disp(' ');
            disp(' *********************************************************');
            app.ROIsevaluationButton.Enable = 'on';            
            
        end

        % Button pushed function: ROIsevaluationButton
        function ROIsevaluationButtonPushed(app, event)
            evalin('base','importMSv.ROIplotGUI.current_ROI=1;');
            chrom2D=evalin('base','importMSv.options.chrom2D;');
            ROIplotGUIsep;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FigureSepOne = uifigure('Visible', 'off');
            app.FigureSepOne.Name = 'importMS - Separations: single file analysis';
            app.SeparationssinglefileanalysisLabel = uilabel(app.FigureSepOne);
            app.SeparationssinglefileanalysisLabel.Text = 'Separations: single file analysis';
            app.FiledetailsPanel = uipanel(app.FigureSepOne);
            app.FiledetailsPanel.Title = 'File details';
            app.textType = uilabel(app.FiledetailsPanel);
            app.textType.Text = 'Type of data:';
            app.buttonImport = uibutton(app.FiledetailsPanel, 'push');
            app.buttonImport.ButtonPushedFcn = createCallbackFcn(app, @buttonImportButtonPushed, true);
            app.buttonImport.Enable = 'off';
            app.buttonImport.Text = 'Import';
            app.textFilename = uilabel(app.FiledetailsPanel);
            app.textFilename.Text = 'Filename:';
            app.buttonSelect = uibutton(app.FiledetailsPanel, 'push');
            app.buttonSelect.ButtonPushedFcn = createCallbackFcn(app, @buttonSelectButtonPushed, true);
            app.buttonSelect.Text = 'Select File';
            app.textName = uilabel(app.FiledetailsPanel);
            app.textName.Text = '';
            app.ListTypes = uilistbox(app.FiledetailsPanel);
            app.ListTypes.Items = {'netCDF', 'mzXML', 'mzXML (MSconvert)'};
            app.ListTypes.ValueChangedFcn = createCallbackFcn(app, @ListTypesValueChanged, true);
            app.ListTypes.Value = 'netCDF';
            app.SummaryPanel = uipanel(app.FigureSepOne);
            app.SummaryPanel.Title = 'Summary';
            app.NoofelutiontimesisLabel = uilabel(app.SummaryPanel);
            app.NoofelutiontimesisLabel.Text = 'No. of elution times is';
            app.textNoElution = uilabel(app.SummaryPanel);
            app.textNoElution.Text = '';
            app.MSmaximumintensityLabel = uilabel(app.SummaryPanel);
            app.MSmaximumintensityLabel.Text = 'MS maximum intensity';
            app.textMaxInt = uilabel(app.SummaryPanel);
            app.textMaxInt.Text = '';
            app.mzrangeLabel = uilabel(app.SummaryPanel);
            app.mzrangeLabel.Text = 'm/z range';
            app.textMZrangeLow = uilabel(app.SummaryPanel);
            app.textMZrangeLow.Text = '';
            app.textMZrangeHigh = uilabel(app.SummaryPanel);
            app.textMZrangeHigh.Text = '';
            app.Label = uilabel(app.SummaryPanel);
            app.Label.Text = '-';
            app.buttonTIC = uibutton(app.SummaryPanel, 'push');
            app.buttonTIC.ButtonPushedFcn = createCallbackFcn(app, @buttonTICButtonPushed, true);
            app.buttonTIC.Enable = 'off';
            app.buttonTIC.Text = 'Plot TIC';
            app.OptionsPanel = uipanel(app.FigureSepOne);
            app.OptionsPanel.Title = 'Options';
            app.check2D = uicheckbox(app.OptionsPanel);
            app.check2D.ValueChangedFcn = createCallbackFcn(app, @check2DValueChanged, true);
            app.check2D.Enable = 'off';
            app.check2D.Text = '2D chomatography?';
            app.checkMZfilter = uicheckbox(app.OptionsPanel);
            app.checkMZfilter.ValueChangedFcn = createCallbackFcn(app, @checkMZfilterValueChanged, true);
            app.checkMZfilter.Enable = 'off';
            app.checkMZfilter.Text = 'mz filter?';
            app.LowmzLabel = uilabel(app.OptionsPanel);
            app.LowmzLabel.Text = 'Low mz';
            app.HighmzLabel = uilabel(app.OptionsPanel);
            app.HighmzLabel.Text = 'High mz';
            app.editLowMZ = uieditfield(app.OptionsPanel, 'text');
            app.editLowMZ.ValueChangedFcn = createCallbackFcn(app, @editLowMZValueChanged, true);
            app.editLowMZ.Enable = 'off';
            app.editHighMZ = uieditfield(app.OptionsPanel, 'text');
            app.editHighMZ.ValueChangedFcn = createCallbackFcn(app, @editHighMZValueChanged, true);
            app.editHighMZ.Enable = 'off';
            app.checkTargeted = uicheckbox(app.OptionsPanel);
            app.checkTargeted.ValueChangedFcn = createCallbackFcn(app, @checkTargetedValueChanged, true);
            app.checkTargeted.Enable = 'off';
            app.checkTargeted.Text = 'Targeted analysis?';
            app.VariablenamewithmzvaluesLabel = uilabel(app.OptionsPanel);
            app.VariablenamewithmzvaluesLabel.Text = 'Variable name with m/z values';
            app.AllowederrorDaLabel = uilabel(app.OptionsPanel);
            app.AllowederrorDaLabel.Text = 'Allowed error (Da)';
            app.editTargMZ = uieditfield(app.OptionsPanel, 'text');
            app.editTargMZ.ValueChangedFcn = createCallbackFcn(app, @editTargMZValueChanged, true);
            app.editTargMZ.Enable = 'off';
            app.editTargError = uieditfield(app.OptionsPanel, 'text');
            app.editTargError.ValueChangedFcn = createCallbackFcn(app, @editTargErrorValueChanged, true);
            app.editTargError.Enable = 'off';
            app.checkCut = uicheckbox(app.OptionsPanel);
            app.checkCut.ValueChangedFcn = createCallbackFcn(app, @checkCutValueChanged, true);
            app.checkCut.Enable = 'off';
            app.checkCut.Text = 'Cut elution profiles?';
            app.SelectelutionprofilesfromLabel = uilabel(app.OptionsPanel);
            app.SelectelutionprofilesfromLabel.Text = 'Select elution profiles from';
            app.editLowTime = uieditfield(app.OptionsPanel, 'text');
            app.editLowTime.ValueChangedFcn = createCallbackFcn(app, @editLowTimeValueChanged, true);
            app.editLowTime.Enable = 'off';
            app.toLabel = uilabel(app.OptionsPanel);
            app.toLabel.Text = 'to';
            app.editHighTime = uieditfield(app.OptionsPanel, 'text');
            app.editHighTime.ValueChangedFcn = createCallbackFcn(app, @editHighTimeValueChanged, true);
            app.editHighTime.Enable = 'off';
            app.ROIalgorithmparametersPanel = uipanel(app.FigureSepOne);
            app.ROIalgorithmparametersPanel.Title = 'ROI algorithm parameters';
            app.textSignal = uilabel(app.ROIalgorithmparametersPanel);
            app.textSignal.Text = 'Signal intensity threshold';
            app.textFactor = uilabel(app.ROIalgorithmparametersPanel);
            app.textFactor.Text = 'Factor signal (x threshold)';
            app.textMZerror = uilabel(app.ROIalgorithmparametersPanel);
            app.textMZerror.Text = 'Mas error tolerance';
            app.textMZunits = uilabel(app.ROIalgorithmparametersPanel);
            app.textMZunits.Text = 'Mass units';
            app.textOcurrences = uilabel(app.ROIalgorithmparametersPanel);
            app.textOcurrences.Text = 'Minimum number of occurrences';
            app.textMZcalc = uilabel(app.ROIalgorithmparametersPanel);
            app.textMZcalc.Text = 'm/z values calculation';
            app.editSignal = uieditfield(app.ROIalgorithmparametersPanel, 'text');
            app.editSignal.ValueChangedFcn = createCallbackFcn(app, @editSignalValueChanged, true);
            app.editSignal.Enable = 'off';
            app.editFactor = uieditfield(app.ROIalgorithmparametersPanel, 'text');
            app.editFactor.ValueChangedFcn = createCallbackFcn(app, @editFactorValueChanged, true);
            app.editFactor.Enable = 'off';
            app.editMZerror = uieditfield(app.ROIalgorithmparametersPanel, 'text');
            app.editMZerror.ValueChangedFcn = createCallbackFcn(app, @editMZerrorValueChanged, true);
            app.editMZerror.Enable = 'off';
            app.listMZunits = uilistbox(app.ROIalgorithmparametersPanel);
            app.listMZunits.Items = {'daltons', 'ppm', ''};
            app.listMZunits.ValueChangedFcn = createCallbackFcn(app, @listMZunitsValueChanged, true);
            app.listMZunits.Enable = 'off';
            app.listMZunits.Value = 'daltons';
            app.editOcurrences = uieditfield(app.ROIalgorithmparametersPanel, 'text');
            app.editOcurrences.ValueChangedFcn = createCallbackFcn(app, @editOcurrencesValueChanged, true);
            app.editOcurrences.Enable = 'off';
            app.dropMZcalc = uidropdown(app.ROIalgorithmparametersPanel);
            app.dropMZcalc.Items = {'default: mean', 'median', 'weighted', 'max'};
            app.dropMZcalc.ValueChangedFcn = createCallbackFcn(app, @dropMZcalcValueChanged, true);
            app.dropMZcalc.Tag = 'mz';
            app.dropMZcalc.Enable = 'off';
            app.dropMZcalc.Value = 'default: mean';
            app.RunROIButton = uibutton(app.FigureSepOne, 'push');
            app.RunROIButton.ButtonPushedFcn = createCallbackFcn(app, @RunROIButtonPushed, true);
            app.RunROIButton.Enable = 'off';
            app.RunROIButton.Text = 'Run ROI';
            app.ROIsevaluationButton = uibutton(app.FigureSepOne, 'push');
            app.ROIsevaluationButton.ButtonPushedFcn = createCallbackFcn(app, @ROIsevaluationButtonPushed, true);
            app.ROIsevaluationButton.Enable = 'off';
            app.ROIsevaluationButton.Text = 'ROIs evaluation';
            % Show the figure after all components are created
            app.FigureSepOne.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prueba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FigureSepOne)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureSepOne)
        end
    end
end