classdef result_visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResultsvisualizationPanel      matlab.ui.container.Panel
        GraphicalrepresentationButton  matlab.ui.control.Button
        PrecursorioninformationPanel   matlab.ui.container.Panel
        UITable_prec                   matlab.ui.control.Table
        UITable_comp                   matlab.ui.control.Table
        RelintensityLabel              matlab.ui.control.Label
        PrecursormzDropDown            matlab.ui.control.DropDown
        PrecursormzDropDownLabel       matlab.ui.control.Label
        ComponentnumberDropDown        matlab.ui.control.DropDown
        ComponentnumberDropDownLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','ROIMCR_identification.visualization.component=[];')
            evalin('base','ROIMCR_identification.visualization.precursor=[];')
            evalin('base','ROIMCR_identification.visualization.precursor_table = {};')
            evalin('base','ROIMCR_identification.visualization.compounds_table = {};')

            app.UITable_prec.Data = evalin('base','ROIMCR_identification.visualization.precursor_table;');
            app.UITable_comp.Data = evalin('base','ROIMCR_identification.visualization.compounds_table;');
            app.UITable_comp.ColumnFormat = {'char','numeric','char','char','numeric','numeric','numeric','numeric','numeric','numeric','numeric'};
        end

        % Drop down opening function: ComponentnumberDropDown
        function ComponentnumberDropDownOpening(app, event)
            results = evalin('base','ROIMCR_identification.ROIMCR_identification');
            n_comp = {'select'};
            for i = 2:size(results,1)+1;
                n_comp{i} = num2str(i-1);
            end

            app.ComponentnumberDropDown.Items = n_comp;
        end

        % Value changed function: ComponentnumberDropDown
        function ComponentnumberDropDownValueChanged(app, event)
            component = app.ComponentnumberDropDown.Value;
            component = str2num(component);

            assignin('base','component',component);
            evalin('base','ROIMCR_identification.visualization.component=component;'); 
            evalin('base','clear component');
            
        end

        % Drop down opening function: PrecursormzDropDown
        function PrecursormzDropDownOpening(app, event)
            component = evalin('base','ROIMCR_identification.visualization.component;');
            result_ident = evalin('base','ROIMCR_identification.ROIMCR_identification;');

            chem = result_ident(component).RESULTS;
            for i=1:size(chem,1)
                precursor{i} = strcat(num2str(chem(i).MCR_PRECURSOR_ION),' (', num2str(chem(i).INTENSITY_PREC_ION),')');
            end

            app.PrecursormzDropDown.Items = precursor;        
        end

        % Value changed function: PrecursormzDropDown
        function PrecursormzDropDownValueChanged(app, event)
            value = app.PrecursormzDropDown.Value;
            value = split(value,' (');

            component = evalin('base','ROIMCR_identification.visualization.component;');
            result_ident = evalin('base','ROIMCR_identification.ROIMCR_identification;');

            chem = result_ident(component).RESULTS;
            precursors_component = [chem.MCR_PRECURSOR_ION];

            prec = find(round(precursors_component,4) == str2num(value{1}));

            assignin('base','prec',prec);
            evalin('base','ROIMCR_identification.visualization.precursor=prec;');
            evalin('base','clear prec');

            precursor = transpose(struct2cell(chem(prec)))
            
            %Data precursor ion
            assignin('base','precursor',precursor);
            evalin('base','ROIMCR_identification.visualization.precursor_table = precursor;')
            evalin('base','clear precursor');

            %Show in table_prec
            display_prec = evalin('base','ROIMCR_identification.visualization.precursor_table;');
            display_prec{3} = size(display_prec{3},1);
            if display_prec{3} == 1
                display_prec{3} = int8(display_prec{3});
            end
            display_prec{4} = int8(size(display_prec{4},1));
            app.UITable_prec.Data = display_prec;

            %Show the compounds identified
            compounds = evalin('base','ROIMCR_identification.visualization.precursor_table;');
            compounds = compounds{4};

            field_names = {'NAME','PRECURSORMZ','PRECURSORTYPE','INCHIKEY','MS2','Similarity','Correlation','p_value','Precursor_ppm'};
            n_fields = length(field_names);

            for i = 1:n_fields
                field_actual = field_names{i}
                if strcmp(field_actual, 'MS2')
                    compounds_dis.(field_actual) = {compounds.(field_actual)};
                else
                    compounds_dis.(field_actual) = [compounds.(field_actual)];
                end
            end

            assignin('base','compounds_dis',compounds_dis);
            evalin('base','ROIMCR_identification.visualization.compounds_table = compounds_dis;')
            evalin('base','clear compounds_dis');

            %convert MS2
            compounds_dis = transpose(struct2cell(compounds_dis));
            ident = size(compounds_dis,1);
            for i = 1:9
                field = compounds_dis(:,i);
                if i == 5
                    for j = 1:ident
                        field{j} = int8(size(field{j},1));
                    end
                else
                    for j = 1:ident
                        field{j} = char(field{j})
                    end
                end
                compounds_dis(:,i) = field;
            end
            app.UITable_comp.Data = compounds_dis;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ResultsvisualizationPanel = uipanel(app.UIFigure);
            app.ResultsvisualizationPanel.Title = 'Results visualization';
            app.ComponentnumberDropDownLabel = uilabel(app.ResultsvisualizationPanel);
            app.ComponentnumberDropDownLabel.Text = 'Component number';
            app.ComponentnumberDropDown = uidropdown(app.ResultsvisualizationPanel);
            app.ComponentnumberDropDown.Items = {};
            app.ComponentnumberDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ComponentnumberDropDownOpening, true);
            app.ComponentnumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentnumberDropDownValueChanged, true);
            app.ComponentnumberDropDown.Value = {};
            app.PrecursormzDropDownLabel = uilabel(app.ResultsvisualizationPanel);
            app.PrecursormzDropDownLabel.Text = 'Precursor mz';
            app.PrecursormzDropDown = uidropdown(app.ResultsvisualizationPanel);
            app.PrecursormzDropDown.Items = {};
            app.PrecursormzDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PrecursormzDropDownOpening, true);
            app.PrecursormzDropDown.ValueChangedFcn = createCallbackFcn(app, @PrecursormzDropDownValueChanged, true);
            app.PrecursormzDropDown.Value = {};
            app.RelintensityLabel = uilabel(app.ResultsvisualizationPanel);
            app.RelintensityLabel.Text = '(Rel intensity)';
            app.UITable_comp = uitable(app.ResultsvisualizationPanel);
            app.UITable_comp.ColumnName = {'Compound'; 'Precursor m/z'; 'Adduct'; 'Inchi key'; 'Nº MS2'; 'Similarity'; 'Correlation'; 'p-value'; 'precursor error'};
            app.UITable_comp.RowName = {};
            app.PrecursorioninformationPanel = uipanel(app.ResultsvisualizationPanel);
            app.PrecursorioninformationPanel.Title = 'Precursor ion information';
            app.UITable_prec = uitable(app.PrecursorioninformationPanel);
            app.UITable_prec.ColumnName = {'m/z'; 'Rel Intensity'; 'Nº MS2'; 'Nº of identifications'};
            app.UITable_prec.RowName = {};
            app.UITable_prec.Tooltip = {''};
            app.GraphicalrepresentationButton = uibutton(app.ResultsvisualizationPanel, 'push');
            app.GraphicalrepresentationButton.Text = {'Graphical'; 'representation'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = result_visualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end