classdef result_visualization_23 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MSidentresultsvisualizationUIFigure  matlab.ui.Figure
        ResultsvisualizationPanel      matlab.ui.container.Panel
        AllionsCheckBox                matlab.ui.control.CheckBox
        GraphicalrepresentationButton  matlab.ui.control.Button
        PrecursorioninformationPanel   matlab.ui.container.Panel
        UITable_prec                   matlab.ui.control.Table
        UITable_comp                   matlab.ui.control.Table
        RelintensityLabel              matlab.ui.control.Label
        PrecursormzDropDown            matlab.ui.control.DropDown
        PrecursormzLabel               matlab.ui.control.Label
        ComponentnumberDropDown        matlab.ui.control.DropDown
        ComponentnumberDropDownLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base','ROIMCR_identification.visualization.component=[];')
            evalin('base','ROIMCR_identification.visualization.precursor=[];')
            evalin('base','ROIMCR_identification.visualization.compound=[];')
            evalin('base','ROIMCR_identification.visualization.compound_ms2=[];')
            evalin('base','ROIMCR_identification.visualization.precursor_table = {};')
            evalin('base','ROIMCR_identification.visualization.compounds_table = {};')
            evalin('base','ROIMCR_identification.visualization.check = 0;')

            app.UITable_prec.Data = evalin('base','ROIMCR_identification.visualization.precursor_table;');
            app.UITable_comp.Data = evalin('base','ROIMCR_identification.visualization.compounds_table;');
            app.UITable_comp.ColumnFormat = {'char','numeric','char','char','numeric','numeric','numeric','numeric','numeric','numeric','numeric'};

            %Delete the database variables from GUI and workspace to increase computing time
            evalin('base','ROIMCR_identification.settings_identification.database=[];'); 

            db_name = evalin('base','ROIMCR_identification.settings_identification.databasename;');
            evalin('base','ROIMCR_identification.settings_identification.databasename=[];');
            try
                assignin('base',db_name,[]);
                evalin('base', ['clear ' db_name]);
            end
        end

        % Drop down opening function: ComponentnumberDropDown
        function ComponentnumberDropDownOpening(app, event)
            results = evalin('base','ROIMCR_identification.ROIMCR_identification');
            n_comp = {'select component'};
            comp_ident = component_identified(results);
            for i = 2:size(comp_ident,1)+1;
                n_comp{i} = num2str(comp_ident(i-1));
            end

            app.ComponentnumberDropDown.Items = n_comp;
        end

        % Value changed function: ComponentnumberDropDown
        function ComponentnumberDropDownValueChanged(app, event)
            component = app.ComponentnumberDropDown.Value;
            component = str2num(component);

            assignin('base','component',component);
            evalin('base','ROIMCR_identification.visualization.component=component;'); 
            evalin('base','clear component');
            
        end

        % Drop down opening function: PrecursormzDropDown
        function PrecursormzDropDownOpening(app, event)
            component = evalin('base','ROIMCR_identification.visualization.component;');
            result_ident = evalin('base','ROIMCR_identification.ROIMCR_identification;');
            precursor = {'Select MS1 m/z'};
            check_v = evalin('base','ROIMCR_identification.visualization.check;');

            try
                chem = result_ident(component).RESULTS;
                if check_v == 0
                    sig_ide = ~arrayfun(@(x) isempty(x.IDENTIFICATIONS), chem);
                    chem = chem(sig_ide);
                else
                end

                for i=2:size(chem,1)+1
                    precursor{i} = strcat(num2str(chem(i-1).MCR_PRECURSOR_ION),' (', num2str(chem(i-1).INTENSITY_PREC_ION),')');
                end

                app.PrecursormzDropDown.Items = precursor;
            catch e1
                if strcmp(e1.identifier, 'MATLAB:needMoreRhsOutputs')
                    errordlg('First you must to chose a MCR component' , 'Error', 'modal');
                else
                    rethrow(e1)
                end
            end
        end

        % Value changed function: PrecursormzDropDown
        function PrecursormzDropDownValueChanged(app, event)
            value = app.PrecursormzDropDown.Value;
            value = split(value,' (');

            component = evalin('base','ROIMCR_identification.visualization.component;');
            result_ident = evalin('base','ROIMCR_identification.ROIMCR_identification;');

            chem = result_ident(component).RESULTS;
            precursors_component = [chem.MCR_PRECURSOR_ION];

            prec = find(round(precursors_component,4) == str2num(value{1}));

            assignin('base','prec',prec);
            evalin('base','ROIMCR_identification.visualization.precursor=prec;');
            evalin('base','clear prec');

            precursor = transpose(struct2cell(chem(prec)));
            
            %Data precursor ion
            assignin('base','precursor',precursor);
            evalin('base','ROIMCR_identification.visualization.precursor_table = precursor;')
            evalin('base','clear precursor');

            %Show in table_prec
            display_prec = evalin('base','ROIMCR_identification.visualization.precursor_table;');
            display_prec{3} = size(display_prec{3},1);
            if display_prec{3} == 1
                display_prec{3} = int8(display_prec{3});
            end
            display_prec{4} = int8(size(display_prec{4},1));
            app.UITable_prec.Data = display_prec;

            %Show the compounds identified
            compounds = evalin('base','ROIMCR_identification.visualization.precursor_table;');
            compounds = compounds{4};

            field_names = {'NAME','PRECURSORMZ','PRECURSORTYPE','INCHIKEY','MS2','Similarity','Correlation','p_value','Precursor_ppm'};
            n_fields = length(field_names);
            try
                for i = 1:n_fields
                    field_actual = field_names{i};
                    if strcmp(field_actual, 'MS2')
                        compounds_dis.(field_actual) = {compounds.(field_actual)};
                    else
                        compounds_dis.(field_actual) = [compounds.(field_actual)];
                    end
                end

                assignin('base','compounds_dis',compounds_dis);
                evalin('base','ROIMCR_identification.visualization.compounds_table = compounds_dis;')
                evalin('base','clear compounds_dis');

                %convert MS2
                compounds_dis = transpose(struct2cell(compounds_dis));
                ident = size(compounds_dis{1},2);
                for i = [1,3,4,5]
                    field = compounds_dis{:,i};
                    if i == 5
                        for j = 1:ident
                            field{j} = int8(size(field{j},1));
                        end
                    else
                        for j = 1:ident
                            field{j} = char(field{j});
                        end
                    end
                    compounds_dis{:,i} = field;
                end
            
                compounds_dis_new = {};
                for i = 1:9
                    id = compounds_dis{i};
                    if i == 1 | i ==3 | i==4
                        for j = 1:ident
                            compounds_dis_new{j,i} = char(id(j));
                        end
                    elseif i == 5
                        for j = 1:ident
                            compounds_dis_new{j,i} = id{j};
                        end
                    else
                        for j = 1:ident
                            compounds_dis_new{j,i} = id(j);
                        end
                    end
                end

                app.UITable_comp.Data = compounds_dis_new;
            catch e1
                if strcmp(e1.identifier, 'MATLAB:nonExistentField')
                    app.UITable_comp.Data = [];
                else
                    rethrow(e1);
                end
            end
        end

        % Selection changed function: UITable_comp
        function UITable_compSelectionChanged(app, event)
            selection = app.UITable_comp.Selection;
            selection = selection(1);
            
            assignin('base','selection',selection);
            evalin('base','ROIMCR_identification.visualization.compound = selection;')
            evalin('base','clear selection');
        end

        % Button pushed function: GraphicalrepresentationButton
        function GraphicalrepresentationButtonPushed(app, event)
            component = evalin('base','ROIMCR_identification.visualization.component;');
            result_ident = evalin('base','ROIMCR_identification.ROIMCR_identification;');
            precursor = evalin('base','ROIMCR_identification.visualization.precursor;');
            compound = evalin('base','ROIMCR_identification.visualization.compound;');

            graphical_representation_ci(result_ident, component, precursor, compound)
        end

        % Cell selection callback: UITable_comp
        function UITable_compCellSelection(app, event)
            indices = event.Indices;
            if indices(2) == 5
                row = indices(1);
                assignin('base','row',row);
                evalin('base','ROIMCR_identification.visualization.compound_ms2=row;')
                evalin('base','clear row');

                ms2_representation_comp
            end
        end

        % Cell selection callback: UITable_prec
        function UITable_precCellSelection(app, event)
            indices = event.Indices;
            if indices(2) == 3
                ms2_representation_prec
            end
        end

        % Value changed function: AllionsCheckBox
        function AllionsCheckBoxValueChanged(app, event)
            value_check = app.AllionsCheckBox.Value;
            
            assignin('base','value_check',value_check);
            evalin('base','ROIMCR_identification.visualization.check=value_check;');
            evalin('base','clear value_check');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MSidentresultsvisualizationUIFigure = uifigure('Visible', 'off');
            app.MSidentresultsvisualizationUIFigure.Name = 'MSident: results visualization';
            app.ResultsvisualizationPanel = uipanel(app.MSidentresultsvisualizationUIFigure);
            app.ResultsvisualizationPanel.Title = 'Results visualization';
            app.ComponentnumberDropDownLabel = uilabel(app.ResultsvisualizationPanel);
            app.ComponentnumberDropDownLabel.Text = 'Component number';
            app.ComponentnumberDropDown = uidropdown(app.ResultsvisualizationPanel);
            app.ComponentnumberDropDown.Items = {'Select component'};
            app.ComponentnumberDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ComponentnumberDropDownOpening, true);
            app.ComponentnumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ComponentnumberDropDownValueChanged, true);
            app.ComponentnumberDropDown.Value = 'Select component';
            app.PrecursormzLabel = uilabel(app.ResultsvisualizationPanel);
            app.PrecursormzLabel.Text = 'Precursor m/z';
            app.PrecursormzDropDown = uidropdown(app.ResultsvisualizationPanel);
            app.PrecursormzDropDown.Items = {'Select MS1 m/z'};
            app.PrecursormzDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PrecursormzDropDownOpening, true);
            app.PrecursormzDropDown.ValueChangedFcn = createCallbackFcn(app, @PrecursormzDropDownValueChanged, true);
            app.PrecursormzDropDown.Value = 'Select MS1 m/z';
            app.RelintensityLabel = uilabel(app.ResultsvisualizationPanel);
            app.RelintensityLabel.Text = '(Rel intensity)';
            app.UITable_comp = uitable(app.ResultsvisualizationPanel);
            app.UITable_comp.ColumnName = {'Compound'; 'Precursor m/z'; 'Adduct'; 'Inchi key'; 'Nº MS2'; 'Similarity'; 'Correlation'; 'p-value'; 'precursor error'};
            app.UITable_comp.RowName = {};
            app.UITable_comp.CellSelectionCallback = createCallbackFcn(app, @UITable_compCellSelection, true);
            app.UITable_comp.SelectionChangedFcn = createCallbackFcn(app, @UITable_compSelectionChanged, true);
            app.PrecursorioninformationPanel = uipanel(app.ResultsvisualizationPanel);
            app.PrecursorioninformationPanel.Title = 'Precursor ion information';
            app.UITable_prec = uitable(app.PrecursorioninformationPanel);
            app.UITable_prec.ColumnName = {'m/z'; 'Rel Intensity'; 'Nº MS2'; 'Nº of identifications'};
            app.UITable_prec.RowName = {};
            app.UITable_prec.CellSelectionCallback = createCallbackFcn(app, @UITable_precCellSelection, true);
            app.UITable_prec.Tooltip = {''};
            app.GraphicalrepresentationButton = uibutton(app.ResultsvisualizationPanel, 'push');
            app.GraphicalrepresentationButton.ButtonPushedFcn = createCallbackFcn(app, @GraphicalrepresentationButtonPushed, true);
            app.GraphicalrepresentationButton.Text = {'Graphical'; 'representation'};
            app.AllionsCheckBox = uicheckbox(app.ResultsvisualizationPanel);
            app.AllionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllionsCheckBoxValueChanged, true);
            app.AllionsCheckBox.Text = 'All ions';
            % Show the figure after all components are created
            app.MSidentresultsvisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = result_visualization_23

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MSidentresultsvisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MSidentresultsvisualizationUIFigure)
        end
    end
end